classdef Oscilloscope < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figure1              matlab.ui.Figure
    saturatedText        matlab.ui.control.Label
    text12               matlab.ui.control.Label
    filterMenu           matlab.ui.control.DropDown
    retriggerButton      matlab.ui.control.Button
    singleSpikeCheckbox  matlab.ui.control.CheckBox
    text10               matlab.ui.control.Label
    vPerDivButton        matlab.ui.control.DropDown
    thresholdSlider      matlab.ui.control.Slider
    text9                matlab.ui.control.Label
    contMSPerDivButton   matlab.ui.control.DropDown
    warnText             matlab.ui.control.Label
    titleText            matlab.ui.control.Label
    savePlotsButton      matlab.ui.control.Button
    clearButton          matlab.ui.control.Button
    startButton          matlab.ui.control.Button
    axes2                matlab.ui.control.UIAxes
    axes1                matlab.ui.control.UIAxes
  end

  
  properties (Access = public)
    contMSPerDiv    % continuous plot ms/division
    contPlotRescale % continuous plot flag for rescale
    contSamples     % continuous plot number of samples in entire plot
    contTimeDivs    % continuous plot time per division
    filter          % current signal filter
    filteredTrace   % trace after filtering
    filters         % set of all filters
    inTrigger       % partway through processing a spike
    lastSpikeIndex  % time of the previous spike
    lbj             % handle to LabJack object
    lbjExists;      % flag whether LabJack hardware is present
    maxContSamples  % maximum samples in continue trace (at slowest time per division)
    plots           % handle to OPlots object
    rawData         % trace of raw data
    sampleRateHz    % LabJack sampling rate in Hz
    samplesRead     % number of samples that have been read
    spikeIndices    % the time of all spikes not yet plotted
    stopAtTraceEnd  % flag to stop data collection at end of current continuous trace
    testMode        % flag for testing mode
    thresholdV      % voltage for spike threshold
    vDivs           % number of voltage divisions on the pots
    vPerDiv         % volts per division (common to both plots)
  end
  
  properties (Access = private)
    dataTimer       % timer for collecting data
    lastButtonTime  % last time the start/stop button was pressed
    signals         % OSignalProcess object
  end
    
  methods (Access = private)
    
    function closeO(app, ~, ~)
      % this function is called  when the user closes the main window
      % close the timer and clear the LabJack handle
      %
      % we won't exit if the task is running
      if strcmp(app.startButton.Text, 'Stop')
        return;
      end
      selection = uiconfirm(app.figure1, 'Really exit? Unsaved data will be lost.', 'Exit Request');
      switch selection
        case 'OK'
          try stop(timerfind); catch, end
          try delete(timerfind); catch, end
          try clear('app.lbj'); catch, end
          delete(app.figure1);
        case 'Cancel'
          return
      end
    end
    
    function collectData(app, ~, ~)
      % reads and processes stream data from the LabJack
      % If the continuous display scaling has just changed, refresh that plot
      if app.contPlotRescale
        setPlotLimits(app);
        clearContPlot(app.plots, app)
        clearTriggerPlot(app.plots, app)
        app.contPlotRescale = false;
      end
      % We need a new trace if we hit or exceeded the sample buffer limit on the previous call
      if app.samplesRead >= app.contSamples
        app.samplesRead = 0;
        app.lastSpikeIndex = app.lastSpikeIndex - app.contSamples;
        if app.stopAtTraceEnd                           % if single trace, stop data collection
          app.plots.samplesPlotted = 0;
          doStartButton(app);
        end
      end
      if (app.plots.samplesPlotted > app.samplesRead)
        if app.plots.singleSpike
          clearContPlot(app.plots, app);
        else
          clearAll(app.plots, app);
        end
      end
      
      % read and process any new data
      
      [dRaw, ~] = getStreamData(app.lbj);                 % get stream data
      if isempty(dRaw)
        return;
      end

      % the LabJack marks lost data as -9999.  We clip it out and don't do anything more. Typically lost data appear
      % at the end of the trace (after the buffer has filled).  Oddly, there is generally one spurious large value
      % (+10.115) that appears exactly 8 samples before the first error values.  We clip that out as well.
  
      if app.saturatedText.Visible || (dRaw(1) > 10.0)
        numSaturated = sum(dRaw > 10.0);
        if app.saturatedText.Visible
          if numSaturated < length(dRaw)
            app.saturatedText.Visible = false;
          end
        elseif numSaturated == length(dRaw)
          app.saturatedText.Visible = true;
          return;
        end
      end

      dRaw(dRaw > 10.0) = [];                                     % clip out all saturated values
      dRaw(dRaw == -9999) = [];                                   % clip out all error values
      % now we can process any valid data
      numNew = min(length(dRaw), app.contSamples - app.samplesRead);
      if numNew > 0
        app.rawData(app.samplesRead + 1:app.samplesRead + numNew) = dRaw(1:numNew);
        processSignals(app.signals, app, app.samplesRead, numNew);
        app.samplesRead = app.samplesRead + numNew;
        doPlots(app.plots, app);
        if numNew < length(dRaw)                                      % more data read from LabJack?
          if app.stopAtTraceEnd                                      % if single trace, stop data collection
            app.plots.samplesPlotted = 0;
            app.samplesRead = 0;
            doStartButton(app);
          else
            numNew = length(dRaw) - numNew;
            app.rawData(1:numNew) = dRaw(end - numNew + 1:end);
            processSignals(app.signals, app, 0, numNew);
            app.samplesRead = numNew;
          end
        end
      end
    end
      
    function collectError(~, ~, ~, ~)
            fprintf('Oscilloscope:collectError: data error');
    end
      
    function doStartButton(app)
      % Start the task running
      if strcmp(app.startButton.Text, 'Start')                % if start mode, do the following
        if seconds(diff(datetime([app.lastButtonTime; datetime]))) < 0.5
          return;
        end
        app.stopAtTraceEnd = false;                           % clear any request to stop
        dataCollectRateHz = 25;                               % prevent overflow w/o blocking other activity
        app.dataTimer = timer('Name', 'CollectData', 'ExecutionMode', 'fixedRate',...
            'Period', 1.0 / dataCollectRateHz, 'ErrorFcn', {@app.collectError},...
            'TimerFcn', {@app.collectData}, 'StartDelay', 0.050);   % startDelay allows rest of the gui to execute
        % clear the plots
        if app.plots.singleSpike
            clearContPlot(app.plots, app);
        else
            clearAll(app.plots, app);
            app.lastSpikeIndex = 2 * app.maxContSamples;        % flag start of ISI sequence
        end
        app.startButton.Text = 'Stop';
        app.startButton.BackgroundColor = 'red';                % update button appearance
        OControlState(app, 'off', {app.startButton})
        startStream(app.lbj);                               % start the LabJack
        start(app.dataTimer);                               % start plots, data pickup, and data acquisition
      % Stop at trace end -- we're already running, so it's a the stop button
      elseif strcmp(app.startButton.Text, 'Stop')               % flag a stop at end of trace
        app.stopAtTraceEnd = true;
        app.startButton.Text = 'Stopping';
        app.startButton.BackgroundColor = 'yellow';
        OControlState(app, 'on', {app.startButton, app.clearButton, app.savePlotsButton});
        app.lastButtonTime = datetime;
        drawnow;
      % Stop immediately -- we're already stopping, so we've been asked to stop immediately
      else 
        stop(timerfind);                                      % stop/delete timers; pause data stream
        delete(timerfind);
        app.dataTimer = 0;
        stopStream(app.lbj);
        app.inTrigger = false;
        app.startButton.Text = 'Start';
        app.startButton.BackgroundColor = 'green';
        OControlState(app, 'on', {app.startButton})
        app.lastButtonTime = datetime;
        drawnow;
      end
    end
      
    % function lbj = openLabJack(~)
    %     %  get hardware info and do not continue if daq device/drivers unavailable
    % 
    %   lbj = labJackU6;                        % create the daq object
    %   open(lbj);                              % open connection to the daq
    %   if isempty(lbj.handle)
    %     originalSize = get(0, 'DefaultUIControlFontSize');
    %     set(0, 'DefaultUIControlFontSize', 14);
    %     questdlg('Exiting. Check USB connections?', 'No LabJack Device Found', 'OK', 'OK');
    %     set(0, 'DefaultUIControlFontSize', originalSize);
    %     return;
    %   else
    %     fprintf(1, ' LabJack Ready\n\n');
    %   end
    %   % create input channel list
    %   removeChannel(lbj, -1);                     % remove all input channels
    %   addChannel(lbj, 0, 10, ['s' 's']);          % add channel 0 as input
    %   lbj.SampleRateHz = 5000;                    % sample rate (Hz)
    %   lbj.ResolutionADC = 1;                      % ADC resolution (AD bit depth)
    % 
    %   % configure LabJack for analog input streaming
    % 
    %   errorCode = streamConfigure(lbj);
    %   if errorCode > 0
    %     fprintf(1,'Oscilloscope: Unable to configure LabJack. Error %d.\n',errorCode);
    %     return
    %   end
    % end
    
    function selectFilter(app)
      app.filter = app.filters{contains(app.filterMenu.Items, app.filterMenu.Value)};
    end
    
    %% setPlotLimits -- used when plot scaling changes
    function setPlotLimits(app)
      app.samplesRead = 0;
      app.spikeIndices = [];
      app.contSamples = app.contMSPerDiv / 1000.0 * app.sampleRateHz * app.contTimeDivs;
      vLimit = app.vPerDiv * app.vDivs / 2.0;
      app.thresholdV = max(-vLimit * 0.9, min(vLimit * 0.9, app.thresholdSlider.Value));
      app.thresholdSlider.Value = app.thresholdV;
      app.thresholdSlider.Limits = [-vLimit, vLimit];
    end
    
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function openO(app, varargin)
      set(app.figure1, 'visible', 'off');
      % Check for existing instance using tag
      if ~isFirstAppInstance(app.figure1)
        delete(app);
        return;
      end
      % We need the following to align the slider with the spike window.
      drawnow;                         % let UIFigure finish laying itself out
      app.axes2.PositionConstraint = 'innerposition';
      app.thresholdSlider.Position([2 4]) = app.axes2.InnerPosition([2 4]);
      
      % varargin   command line arguments to O (see VARARGIN)      
      % test mode requires connecting DAC0 to AIN0 and DAC1 to AIN1 on the LabJack
      fprintf(1,'\nOscilloscope v2.6\n %s\n', string(datetime));
      cleanOldFiles('OData');
      if ~isempty(varargin)
        app.testMode = strcmp(varargin{1}, 'debug') || strcmp(varargin{1}, 'test');
      else
        app.testMode = false;
      end
      if app.testMode
        app.warnText.Text = 'Test Mode';
      end
      app.contMSPerDiv = str2double(app.contMSPerDivButton.Value);
      app.contTimeDivs = 20;
      app.inTrigger = false;
      app.lastButtonTime = datetime;
      app.samplesRead = 0;
      app.stopAtTraceEnd = false;
      app.thresholdV = 1.0;
      app.vDivs = 6;
      app.vPerDiv = 1.0;
    
      set(app.figure1, 'CloseRequestFcn', {@app.closeO});           % close function will close LabJack
      [app.lbj, app.lbjExists] = setupLabJack(app.testMode, 5000);
      if isempty(app.lbj)
        app.delete;
        return;
      end
      app.sampleRateHz = app.lbj.SampleRateHz;         
      maxMSPerDiv = str2double(app.contMSPerDivButton.Items{end});
      app.maxContSamples = app.contTimeDivs * maxMSPerDiv / 1000.0 * app.sampleRateHz;
      app.rawData = zeros(app.maxContSamples, 1);                   % raw data
      app.filteredTrace = zeros(app.maxContSamples, 1);             % filtered voltage trace
      setPlotLimits(app);                                           % set parameters for plots (after sampleRateHz)
      % make filters, usings the values in the filter menu
      filterStrings = app.filterMenu.Items;
      app.filters = cell(length(filterStrings), 1);
      for f = 1:length(filterStrings)
        cutOffHz = str2double(filterStrings{f});
        theFilter = design(fdesign.highpass('Fst,Fp,Ast,Ap', 0.5 * cutOffHz / app.sampleRateHz, ...
          2 * cutOffHz / app.sampleRateHz, 60, 1), 'butter');
        theFilter.persistentmemory = true;         % piecemeal filtering of trace
        theFilter.states = 1;                      % uses scalar expansion.
        app.filters{f} = theFilter;
      end
      selectFilter(app);
      app.signals = OSignalProcess(app);                            % signal processing
      app.plots = OPlots(app);                                      % plots 
      movegui(app.figure1, 'northeast');
      set(app.figure1, 'visible', 'on');      
    end

    % Button pushed function: clearButton
    function clearButton_Callback(app, event)
      app.spikeIndices = [];
      app.inTrigger = false;
      app.samplesRead = 0;
      app.lastSpikeIndex = 2 * app.maxContSamples;                    % flag start with invalid index
      clearAll(app.plots, app);
    end

    % Value changed function: contMSPerDivButton
    function contMSPerDivButton_Callback(app, event)
      newValue = str2double(app.contMSPerDivButton.Value);
      if newValue ~= app.contMSPerDiv
        app.contMSPerDiv = newValue;
        if strcmp(app.startButton.Text, 'Start')          % if we're not running
          setPlotLimits(app);
          clearContPlot(app.plots, app);
        else
          app.contPlotRescale = true;          % else let dataCollect do the update
        end
      end
    end

    % Value changed function: filterMenu
    function filterMenu_Callback(app, event)
      selectFilter(app);
    end

    % Button pushed function: retriggerButton
    function retriggerButton_Callback(app, event)
      clearTriggerPlot(app.plots, app);
    end

    % Button pushed function: savePlotsButton
    function savePlotsButton_Callback(app, event)
      OControlState(app, 'off', {})
      progress = uiprogressdlg(app.figure1, 'title', 'Save Plots', 'message', ...
        ['Saving to ', dataRoot(), '/OData'], 'indeterminate','on');
      timeString = savePlot(app.axes1, 'OData/FullTraces/', 'OSC', 'png');
      savePlot(app.axes2, 'OData/FastTraces/', 'OSC', 'png', timeString);
      close(progress);
      OControlState(app, 'on', {});
    end

    % Value changed function: singleSpikeCheckbox
    function singleSpikeCheckbox_Callback(app, event)
      app.plots.singleSpike = app.singleSpikeCheckbox.Value;
      if app.plots.singleSpike
        app.retriggerButton.Enable = 'on';
        clearTriggerPlot(app.plots, app);
      else
        app.retriggerButton.Enable = 'off';
      end
    end

    % Callback function
    function singleTraceCheckbox_Callback(app, event)
      app.singleTrace = app.singleSpikeCheckbox.Value;
    end

    % Button pushed function: startButton
    function startButton_Callback(app, event)
      doStartButton(app);
    end

    % Value changed function: thresholdSlider
    function thresholdSlider_Callback(app, event)
      app.thresholdV = app.thresholdSlider.Value;
      updateThresholdIfNeeded(app.plots, app);
    end

    % Value changed function: vPerDivButton
    function vPerDivButton_Callback(app, event)
      newValue = str2double(app.vPerDivButton.Value);
      if newValue ~= app.vPerDiv
        app.vPerDiv = newValue;
        if strcmp(app.startButton.Text, 'Start')      % if we're not running
          setPlotLimits(app);
          clearAll(app.plots, app);
        else
          app.contPlotRescale = true;                % else let dataCollect do the update
        end
      end
    end

    % Value changing function: thresholdSlider
    function thresholdSlider_ValueChanging(app, event)
    app.thresholdV = event.Value;              % <-- continuous value while dragging
    updateThresholdIfNeeded(app.plots, app);
    % Optional: keep UI responsive if you notice lag
    % drawnow limitrate nocallbacks      
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.figure1 = uifigure('Visible', 'off');
      colormap(app.figure1, 'parula');
      app.figure1.Tag = 'Oscilloscope';
      app.axes1 = uiaxes(app.figure1);
      app.axes1.Tag = 'axes1';
      app.axes2 = uiaxes(app.figure1);
      app.axes2.Tag = 'axes2';
      app.startButton = uibutton(app.figure1, 'push');
      app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButton_Callback, true);
      app.startButton.Tag = 'startButton';
      app.startButton.Text = 'Start';
      app.clearButton = uibutton(app.figure1, 'push');
      app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButton_Callback, true);
      app.clearButton.Tag = 'clearButton';
      app.clearButton.Text = 'Clear Data';
      app.savePlotsButton = uibutton(app.figure1, 'push');
      app.savePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @savePlotsButton_Callback, true);
      app.savePlotsButton.Tag = 'savePlotsButton';
      app.savePlotsButton.Text = 'Save Plots';
      app.titleText = uilabel(app.figure1);
      app.titleText.Tag = 'titleText';
      app.titleText.Text = 'NSCI 20100 Oscilloscope';
      app.warnText = uilabel(app.figure1);
      app.warnText.Tag = 'warnText';
      app.warnText.Text = '';
      app.contMSPerDivButton = uidropdown(app.figure1);
      app.contMSPerDivButton.Items = {'10', '25', '50', '100', '250', '500', '1000'};
      app.contMSPerDivButton.ValueChangedFcn = createCallbackFcn(app, @contMSPerDivButton_Callback, true);
      app.contMSPerDivButton.Tag = 'contMSPerDivButton';
      app.contMSPerDivButton.Value = '100';
      app.text9 = uilabel(app.figure1);
      app.text9.Tag = 'text9';
      app.text9.Text = ' Time per Division (ms):';
      app.thresholdSlider = uislider(app.figure1);
      app.thresholdSlider.Limits = [-1 1];
      app.thresholdSlider.ValueChangedFcn = createCallbackFcn(app, @thresholdSlider_Callback, true);
      app.thresholdSlider.ValueChangingFcn = createCallbackFcn(app, @thresholdSlider_ValueChanging, true);
      app.thresholdSlider.Tag = 'thresholdSlider';
      app.thresholdSlider.Value = 1;
      app.vPerDivButton = uidropdown(app.figure1);
      app.vPerDivButton.Items = {'0.1', '0.2', '0.5', '1.0', '2.0', '5.0'};
      app.vPerDivButton.ValueChangedFcn = createCallbackFcn(app, @vPerDivButton_Callback, true);
      app.vPerDivButton.Tag = 'vPerDivButton';
      app.vPerDivButton.Value = '1.0';
      app.text10 = uilabel(app.figure1);
      app.text10.Tag = 'text10';
      app.text10.Text = ' Volts per Division:';
      app.singleSpikeCheckbox = uicheckbox(app.figure1);
      app.singleSpikeCheckbox.ValueChangedFcn = createCallbackFcn(app, @singleSpikeCheckbox_Callback, true);
      app.singleSpikeCheckbox.Tag = 'singleSpikeCheckbox';
      app.singleSpikeCheckbox.Text = 'Single Trace';
      app.retriggerButton = uibutton(app.figure1, 'push');
      app.retriggerButton.ButtonPushedFcn = createCallbackFcn(app, @retriggerButton_Callback, true);
      app.retriggerButton.Tag = 'retriggerButton';
      app.retriggerButton.Enable = 'off';
      app.retriggerButton.Text = 'Re-trigger';
      app.filterMenu = uidropdown(app.figure1);
      app.filterMenu.Items = {'1', '100', '250'};
      app.filterMenu.ValueChangedFcn = createCallbackFcn(app, @filterMenu_Callback, true);
      app.filterMenu.Tag = 'filterMenu';
      app.filterMenu.Value = '1';
      app.text12 = uilabel(app.figure1);
      app.text12.Tag = 'text12';
      app.text12.Text = 'High-pass Filter (Hz):';
      app.saturatedText = uilabel(app.figure1);
      app.saturatedText.Tag = 'saturatedText';
      app.saturatedText.Visible = 'off';
      app.saturatedText.Text = 'LabJack A/D saturated -- check input signal to AIN0';
      % Show the figure after all components are created
      app.figure1.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = Oscilloscope(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.figure1)

      % Execute the startup function
      runStartupFcn(app, @(app)openO(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figure1)
    end
  end
end