classdef contrastThresholds < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figure1            matlab.ui.Figure
    axes1              matlab.ui.control.UIAxes
    stimRepsText       matlab.ui.control.EditField
    intertrialDurText  matlab.ui.control.EditField
    prestimDurText     matlab.ui.control.EditField
    stimDurText        matlab.ui.control.EditField
    baseContrastMenu   matlab.ui.control.DropDown
    clearDataButton    matlab.ui.control.Button
    savePlotsButton    matlab.ui.control.Button
    saveDataButton     matlab.ui.control.Button
    runButton          matlab.ui.control.Button
    staticText1        matlab.ui.control.Label
    staticText2        matlab.ui.control.Label
    staticText3        matlab.ui.control.Label
    statusText         matlab.ui.control.Label
    resultsTable       matlab.ui.control.Table
    text21             matlab.ui.control.Label
    text22             matlab.ui.control.Label
    text23             matlab.ui.control.Label
    showHideButton     matlab.ui.control.Button
    loadDataButton     matlab.ui.control.Button
    testWarning        matlab.ui.control.Label
    stimWarning        matlab.ui.control.Label
  end

  properties (Access = public)
    baseContrasts     % the values of the base contrasts
    blocksFit         % flags for which base constrasts have had a function fit
    curveFits         % Description
    doStim            % flag to present or not present visual stimuli
    hits              % Description
    numBases          % the number of base contrasts
    numIncrements     % number of contrast increments per base    
    sampFreqHz        % Description
    stimParams        % Description
    stimStartTimeS    % Description
    stimuli           % stimulus control object
    taskState         % task running, idle, etc
    testContrasts     % specific contrasts to test -- tuned to insure real differences in pixel values
    testIndex         % Description
    testMode          % no user responses
    theKey            % last key pressed
    tones             % Description
    trialsDone        % Description
    trialStartTimeS    % Description
  end
  
methods (Access = public)
  
  function timerErrorFcn(~, ~, app)
    disp('timer error');
  end

end

methods (Access = private)
  function clearOneContrast(app)
    baseIndex = contains(app.baseContrastMenu.Items, app.baseContrastMenu.Value);
    app.trialsDone(baseIndex, :) = 0;
    app.hits(baseIndex, :) = 0;
    app.blocksFit(baseIndex) = 0;
    app.curveFits(baseIndex, :) = 0;
    tableData = app.resultsTable.Data;
    tableData{1, baseIndex} = 0;
    tableData{2, baseIndex} = 0;
    set(app.resultsTable, 'Data', tableData);
  end

  function doShowHide(app)

    if strcmp(get(app.showHideButton, 'Text'), 'Hide Display')
      delete(app.stimuli);
      set(app.showHideButton, 'Text', 'Show Display', 'backgroundColor', 'red');
      set(app.runButton, 'Text', 'Run', 'backgroundColor', [0.9412 0.9412 0.9412]);
      ctControlState(app, 'off', {app.showHideButton});
    elseif strcmp(get(app.showHideButton, 'Text'), 'Show Display')
      app.stimuli = ctStimuli();                  % restore stimulus display
      set(app.showHideButton, 'Text', 'Hide Display','backgroundColor', [0.9412 0.9412 0.9412]);
      set(app.runButton, 'Text', 'Run','backgroundColor', 'green');
      ctControlState(app, 'on', {app.showHideButton});
    end
%     guidata(app.showHideButton, handles);               % save the changes
  end
 
  function keyPressed(app, ~, keyData)
    if strcmp(keyData.Key, 'escape')
      if app.taskState ~= ctTaskState.taskStopped
        app.taskState = ctTaskState.taskStopRunning;
      end
    elseif app.taskState == ctTaskState.taskWaitGoKey
      if strcmp(keyData.Key, 'downarrow')
        app.taskState = ctTaskState.taskDoStim;
      end
    elseif app.taskState == ctTaskState.taskWaitResponse
      if strcmp(keyData.Key, 'leftarrow')
        app.theKey = 'left';
        app.taskState = ctTaskState.taskProcessResponse;
      elseif strcmp(keyData.Key, 'rightarrow')
        app.theKey = 'right';
        app.taskState = ctTaskState.taskProcessResponse;
      end
    end
  end
    
  % Update components that require runtime configuration
  function addRuntimeConfigurations(app)
    
    % Set component properties that require runtime configuration
    app.resultsTable.BackgroundColor = [1 1 1; 0.9608 0.9608 0.9608];
    app.resultsTable.ColumnFormat = {[] [] [] [] []};
  end
end
    

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function openContrastThresholds(app, varargin)
      set(app.figure1, 'visible', 'off');      
      addRuntimeConfigurations(app);           % Add runtime required configuration - Added by Migration Tool
      [~, ~, ~] = convertToGUIDECallbackArguments(app);
       
      app.testMode = true;
      app.doStim = true;
      app.testIndex = 0;
      app.taskState = ctTaskState.taskStopped;
      app.theKey = '';
      app.testContrasts = [ 0.032	0.034 0.045 0.052 0.060;...
                            0.062 0.071 0.084 0.096 0.121;...
                            0.126 0.133 0.140 0.180 0.238;...
                            0.249 0.260 0.271 0.357 0.480;...
                            0.495 0.514 0.550 0.718 0.961];
      app.baseContrasts = [0.03 0.058 0.12 0.24 0.48];
      app.numIncrements = size(app.testContrasts, 2);
      app.numBases = length(app.baseContrasts);
      app.blocksFit = zeros(1, app.numBases);
      app.curveFits = zeros(app.numBases, app.numIncrements + 1);
      app.hits = zeros(app.numBases, app.numIncrements);
      app.stimParams = struct;
      app.trialsDone = zeros(app.numBases, app.numIncrements);
      app.trialStartTimeS = 0;

      contrastStrings = cell(1, app.numBases);
      for i = 1:app.numBases
        contrastStrings{i} = sprintf('%.0f%%', app.baseContrasts(i) * 100.0);
      end
      set(app.baseContrastMenu, 'items', contrastStrings);
      
      audioFiles = {'Tone0250.wav', 'Tone2000.wav', 'Tone4000.wav'};
      app.tones = [];
      for i = 1:length(audioFiles)
        [y, app.sampFreqHz] = audioread(char(audioFiles(i)));
        app.tones = [app.tones y(:)];
      end
      app.tones = app.tones';
      
      tableData = app.resultsTable.Data;
      for c = 1:app.numBases
        for r = 1:2
          tableData{r, c} = 0;
        end
      end
      app.resultsTable.Data = tableData;
     
      rng('shuffle');
%       handles.data = ctTaskData(app);
%       handles.output = hObject;                               % select default command line output
      app.stimuli = ctStimuli();                              % restore stimulus display
      
      %     testStimuli(app.stimuli, handles);                % check the screen for contrasts available
      
      ctDrawStatusText(app, 'idle');
      movegui(app.figure1, [-10, 10]);
      if app.testMode
        set(app.testWarning, 'text', 'Test Mode');
      end
      if ~app.doStim
        set(app.stimWarning, 'text', 'No Stimuli');
      end
      buttonStruct.keyDown = '';                                            % UserDate needs to be a struct
      set(app.runButton, 'userData', buttonStruct);
      set(app.figure1, 'visible', 'on');

%       guidata(hObject, handles);                                    % save the selection
      
      taskTimer = timer('name', 'TaskTimer', 'executionMode', 'fixedRate', 'period', 0.1,...
        'errorFcn', {@(~,~)timerErrorFcn(app)}, 'timerFcn', {@ctTaskController, app});
%         'UserData', handles, 'errorFcn', {@timerErrorFcnStop, handles}, 'timerFcn', {@ctTaskController, app});
      start(taskTimer);
      set(app.figure1, 'WindowKeyPressFcn', @app.keyPressed)
    end

    % Button pushed function: clearDataButton
    function clearDataCallback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [~, ~, ~] = convertToGUIDECallbackArguments(app, event);
      
      if strcmp(get(app.runButton, 'Text'), 'Stop')
        return;
      end
%       baseIndex = get(app.baseContrastMenu, 'value');
%       strings = get(app.baseContrastMenu, 'string');
      selection = uiconfirm(app.figure1, ...
            sprintf('Really clear data for %s base contrast?/n(This cannot be undone.)', ...
            get(app.baseContrastMenu, 'value')), 'Clear Data');
      switch selection
        case 'OK'
          clearOneContrast(app);
          ctDrawHitRates(app, true);
%           guidata(hObject, handles);
        case 'Cancel'
          return
      end
    end

    % Button pushed function: loadDataButton
    function loadDataButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [~, ~, ~] = convertToGUIDECallbackArguments(app, event); 
      
      cleanup(app.stimuli);
      ctControlState(app, 'off', {app.loadDataButton});
      [fileName, filePath] = uigetfile('*.mat', 'Load Matlab Data Workspace', '~/Desktop/');
      if fileName ~= 0
        stop(timerfind);                                            % stop/delete timers; pause data stream
        delete(timerfind);
%         load([filePath fileName], 'd');
%         handles.data = d;
        ctDrawHitRates(app, true);
        taskTimer = timer('name', 'TaskTimer', 'executionMode', 'fixedRate', 'period', 0.1,...
          'errorFcn', {@timerErrorFcn, app}, 'timerFcn', {@ctTaskController});
%         keyTimer = timer('name', 'KeyTimer', 'executionMode', 'fixedRate', 'period', 0.1,...
%           'UserData', handles, 'errorFcn', {@timerErrorFcn, handles}, 'timerFcn', {@app.keyboardCheck});
        start(taskTimer);
%         start(keyTimer);
      end
      app.stimuli = ctStimuli();                  % restore stimulus display
      set(app.runButton, 'backgroundColor', 'green');
      ctControlState(app, 'on', {app.loadDataButton});
%       guidata(hObject, handles);                                      % save the selections
    end

    % Button pushed function: runButton
    function runButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [~, ~, ~] = convertToGUIDECallbackArguments(app, event); 
      
      if strcmp(app.runButton.Text, 'Stop')           % if we are running, just set the abort flag
        app.taskState = ctTaskState.taskStopRunning;
      else
        baseIndex = contains(app.baseContrastMenu.Items, app.baseContrastMenu.Value);
        stimReps = str2double(app.stimRepsText.Value);
%         data = handles.data;
        if sum(app.trialsDone(baseIndex, :)) < stimReps * app.numIncrements
          app.taskState = ctTaskState.taskStartRunning;
        else
          uialert(app.figure1, sprintf(['%d repeats of %s contrast have already been done.\n', ...
                'Increase the value for "Repeasts Before Stop" or change the "Base Contrast"'], ...
                stimReps, app.baseContrastMenu.Value), 'At Repeat Limit');
        end
      end
%       guidata(hObject, handles);                          % save the changes
    end

    % Button pushed function: saveDataButton
    function saveDataButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      
      cleanup(app.stimuli);
      ctControlState(app, 'off', {app.saveDataButton});
      [fileName, filePath] = uiputfile('*.mat', 'Save Matlab Data Workspace', '~/Desktop/ContrastData.mat');
      if fileName ~= 0
%         d = handles.data;
%         save([filePath fileName], 'd');
      end
      app.stimuli = ctStimuli();
      set(app.runButton, 'backgroundColor', 'green');
      ctControlState(app, 'on', {app.saveDataButton});
    end

    % Button pushed function: savePlotsButton
    function savePlotsButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      
      %         cleanup(app.stimuli);
      ctControlState(app, 'off', {});
      progress = uiprogressdlg(app.figure1, 'title', 'Save Plots', 'message', 'Saving to ~/Desktop/CTData', ...
        'indeterminate','on');
      
      timeString = savePlot(app.axes1, '~/Desktop/CTData/Plots/', 'CT', 'png');
      saveTable(app.resultsTable, '~/Desktop/CTData/Tables/', 'CT', timeString);
      close(progress);
      ctControlState(app, 'on', {});
      %         app.stimuli = ctStimuli();
      %       set(app.runButton, 'backgroundColor', 'green');
    end

    % Button pushed function: showHideButton
    function showHideButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      doShowHide(app);
    end

    % Close request function: figure1
    function windowCloseRequest(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [~, ~, ~] = convertToGUIDECallbackArguments(app, event);
      
      if strcmp(get(app.runButton, 'Text'), 'Stop')
        return;
      end
      selection = uiconfirm(app.figure1, 'Really exit? Unsaved data will be lost.', 'Exit Request');
      switch selection
        case 'OK'
          try stop(timerfind); catch, end
          try delete(timerfind); catch, end
          try delete(app.stimuli); catch, end
          delete(app.figure1);                              % close the program window
        case 'Cancel'
          return
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.figure1 = uifigure('Visible', 'off');
      app.figure1.Name = 'NSCI 20100';
      app.figure1.CloseRequestFcn = createCallbackFcn(app, @windowCloseRequest, true);
      app.figure1.Tag = 'figure1';
      app.axes1 = uiaxes(app.figure1);
      app.axes1.XLim = [2 100];
      app.axes1.YLim = [0 100];
      app.axes1.XTick = [5 6 7 8 9 10 20 30 40 50 60 80 100];
      app.axes1.XTickLabel = {'5'; '6'; '7'; '8'; '9'; '10'; '20'; '30'; '40'; '50'; '60'; '80'; '100'};
      app.axes1.YTick = [0 20 40 60 80 100];
      app.axes1.YTickLabel = {'10'; '20'; '40'; '60'; '80'; '100'};
      app.axes1.XScale = 'log';
      app.axes1.Tag = 'axes1';
      app.stimRepsText = uieditfield(app.figure1, 'text');
      app.stimRepsText.Tag = 'stimRepsText';
      app.stimRepsText.Value = '25';
      app.intertrialDurText = uieditfield(app.figure1, 'text');
      app.intertrialDurText.Tag = 'intertrialDurText';
      app.intertrialDurText.Value = '1.0';
      app.prestimDurText = uieditfield(app.figure1, 'text');
      app.prestimDurText.Tag = 'prestimDurText';
      app.prestimDurText.Value = '1.0';
      app.stimDurText = uieditfield(app.figure1, 'text');
      app.stimDurText.Tag = 'stimDurText';
      app.stimDurText.Value = '0.25';
      app.baseContrastMenu = uidropdown(app.figure1);
      app.baseContrastMenu.Items = {'3%', '6%', '12%', '24%', '48%'};
      app.baseContrastMenu.Tag = 'baseContrastMenu';
      app.baseContrastMenu.Value = '48%';
      app.clearDataButton = uibutton(app.figure1, 'push');
      app.clearDataButton.ButtonPushedFcn = createCallbackFcn(app, @clearDataCallback, true);
      app.clearDataButton.Tag = 'clearDataButton';
      app.clearDataButton.Text = 'Clear Data';
      app.savePlotsButton = uibutton(app.figure1, 'push');
      app.savePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @savePlotsButton_Callback, true);
      app.savePlotsButton.Tag = 'savePlotsButton';
      app.savePlotsButton.Text = 'Save Plots';
      app.saveDataButton = uibutton(app.figure1, 'push');
      app.saveDataButton.ButtonPushedFcn = createCallbackFcn(app, @saveDataButton_Callback, true);
      app.saveDataButton.Tag = 'saveDataButton';
      app.saveDataButton.Text = 'Save Data';
      app.runButton = uibutton(app.figure1, 'push');
      app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButton_Callback, true);
      app.runButton.Tag = 'runButton';
      app.runButton.Text = 'Start';
      app.staticText1 = uilabel(app.figure1);
      app.staticText1.Tag = 'staticText1';
      app.staticText1.Text = 'Repeats Before Stop:';
      app.staticText2 = uilabel(app.figure1);
      app.staticText2.Tag = 'staticText2';
      app.staticText2.Text = 'Test Stim. Dur. (s):';
      app.staticText3 = uilabel(app.figure1);
      app.staticText3.Tag = 'staticText3';
      app.staticText3.Text = 'Base Stim. Dur. (s):';
      app.statusText = uilabel(app.figure1);
      app.statusText.Tag = 'statusText';
      app.statusText.Text = 'Status';
      app.resultsTable = uitable(app.figure1);
      app.resultsTable.ColumnName = {'3%'; '6%'; '12%'; '24%'; '48%'};
      app.resultsTable.RowName = {'Repeats Done'; 'Threshold Contrast'};
      app.resultsTable.ColumnEditable = [false false false false false];
      app.resultsTable.Tag = 'resultsTable';
      app.text21 = uilabel(app.figure1);
      app.text21.Tag = 'text21';
      app.text21.Text = 'Base Contrast:';
      app.text22 = uilabel(app.figure1);
      app.text22.Tag = 'text22';
      app.text22.Text = 'Inter-trial Dur. (s):';
      app.text23 = uilabel(app.figure1);
      app.text23.Tag = 'text23';
      app.text23.Text = {'NSCI 20100 Contrast Increment'; 'Thresholds Laboratory'};
      app.showHideButton = uibutton(app.figure1, 'push');
      app.showHideButton.ButtonPushedFcn = createCallbackFcn(app, @showHideButton_Callback, true);
      app.showHideButton.Tag = 'showHideButton';
      app.showHideButton.Text = 'Hide Display';
      app.loadDataButton = uibutton(app.figure1, 'push');
      app.loadDataButton.ButtonPushedFcn = createCallbackFcn(app, @loadDataButton_Callback, true);
      app.loadDataButton.Tag = 'loadDataButton';
      app.loadDataButton.Text = 'Load Data';
      app.testWarning = uilabel(app.figure1);
      app.testWarning.Tag = 'testWarning';
      app.testWarning.Text = '';
      app.stimWarning = uilabel(app.figure1);
      app.stimWarning.Tag = 'stimWarning';
      app.stimWarning.Text = '';
      % Show the figure after all components are created
      app.figure1.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = contrastThresholds(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.figure1)

      % Execute the startup function
      runStartupFcn(app, @(app)openContrastThresholds(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figure1)
    end
  end
end