classdef Metrics < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figure1           matlab.ui.Figure
    posAxes           matlab.ui.control.UIAxes
    avgPosAxes        matlab.ui.control.UIAxes
    velAxes           matlab.ui.control.UIAxes
    avgVelAxes        matlab.ui.control.UIAxes
    ampDurAxes        matlab.ui.control.UIAxes
    startButton       matlab.ui.control.Button
    clearButton       matlab.ui.control.Button
    viewDistanceText  matlab.ui.control.EditField
    text2             matlab.ui.control.Label
    thresholdDegText  matlab.ui.control.EditField
    text3             matlab.ui.control.Label
    savePlotsButton   matlab.ui.control.Button
    titleText         matlab.ui.control.Label
    saveDataButton    matlab.ui.control.Button
    loadDataButton    matlab.ui.control.Button
    warnText          matlab.ui.control.Label
    calibrationText   matlab.ui.control.Label
    Filter60Hz        matlab.ui.control.CheckBox
  end

  
  properties (Access = public)
    absStepIndex = 1;       % index of current step, accounting for sign
    blocksDone = 0;         % number of blocks completed
    calTrialsDone = 0;      % number of calibration trials done
    centeringTrial = false; % flag for centering trial
    dataState = MetricsDataState.dataIdle;  % state of data collection system
    doStimDisplay = true;   % do display or run fast (for test mode)
    filter60Hz;             % 60 Hz notch filter 
    filterLP;               % low pass filter
    lbj;                    % handle to LabJack controller object
    numChannels;            % number of analog channels
    numOffsets;             % number of offsets used
    numSummed;              % number of traces averaged
    offsetsDeg = [5 10 15 20 -5 -10 -15 -20]; % offsets to test
    offsetsDone;            % counts for of offsets done
    posAvg;                 % trace with the average position
    posSummed;              % counts of number of position traces summed
    rawData;                % raw trial samples from LabJack
    saccadeDurS;            % average duration of saccades for each offset
    saccadeSamples;         % number of samples in the saccade trace   
    saccadeTraceS = 0.250;  % duratoin of saccade trace
    testMode = false;       % set this up in openMetrics()
    trialDurS;              % duration of the trial trace
    trialSamples;           % A/D samples in a trial
  end
  
  methods (Access = private)
    
    function closeMetrics(app, ~, ~, handles)
      % this function is called  when the user closes the main window
      % close the timer and clear the LabJack handle
      %
      % fist check whether the task is running
      if strcmp(get(handles.startButton, 'String'), 'Stop')
        return;
      end
      selection = uiconfirm(app.figure1, 'Really exit? Unsaved data will be lost.', 'Exit Request');
      switch selection
        case 'OK'
          try cleanup(handles.visStim); catch, end
          try delete(handles.visStim); catch, end
          try delete(handles.ampDur); catch, end
          try stop(timerfind); catch, end
          try delete(timerfind); catch, end
          try clear('app.lbj'); catch, end
          delete(app.figure1);                                % close the program window
        case 'Cancel'
          return
      end
    end
    
    function clearBuffers(app)
      app.blocksDone = 0;
      app.calTrialsDone = 0;
      app.saccadeDurS = zeros(1, app.numOffsets);                 % average saccade durations
      app.numSummed = zeros(1, app.numOffsets);
      app.offsetsDone = zeros(1, app.numOffsets);
      app.rawData = zeros(app.trialSamples, app.numChannels);     % raw data
      app.posTrace = zeros(app.trialSamples, 1);                  % trial EOG position trace
      app.posSummed = zeros(app.saccadeSamples, app.numOffsets);  % summed position traces
      app.posAvg = zeros(app.saccadeSamples, app.numOffsets);     % averaged position traces
      app.velTrace = zeros(app.trialSamples, 1);                      % trial EOG velocity trace
      app.velSummed = zeros(app.saccadeSamples, app.numOffsets);  % summed position traces
      app.velAvg = zeros(app.saccadeSamples, app.numOffsets);     % averaged position traces
    end
    
    function collectData(app, obj, ~)
      % reads stream data from the LabJack
      handles = obj.UserData;
      data = handles.data;
      switch app.dataState
        case MetricsDataState.dataIdle
          return;
        case MetricsDataState.dataCollect
          [dRaw, ~] = getStreamData(app.lbj);                                     %#ok<*NASGU> % get stream data
          numNew = min(length(dRaw), app.trialSamples - data.samplesRead);
          app.rawData(data.samplesRead + 1:data.samplesRead + numNew, :) = dRaw(1:numNew, :);
          data.samplesRead = data.samplesRead + numNew;
          if (data.samplesRead == app.trialSamples)
            tStart = tic;
            app.lbj.verbose = 0;
            stopStream(app.lbj);
            app.lbj.verbose = 1;
            app.dataState = MetricsDataState.dataIdle;
            data.taskState = MetricsTaskState.taskEndtrial;
          end
      end
    end
    
    function lbj = setupLabJack(app)
      %  get hardware info and do not continue if daq device/drivers unavailable
      
      lbj = labJackU6;                        % create the daq object
      open(lbj);                              % open connection to the daq
      if isempty(lbj.handle)
        selection = uiconfirm(app.figure1, 'No LabJack device found.  Check USB connections?', 'Fatal Error', ...
              'Icon', 'error', 'options', {'Exit'});
        lbj = [];
        return;
      else
        fprintf(1,'Metrics: LabJack Ready.\n\n');
      end
      % create input channel list
      removeChannel(lbj, -1);                     % remove all input channels
      addChannel(lbj, 0, 10, ['s' 's']);          % add channel 0 as input
      lbj.SampleRateHz = 1000;                    % sample rate (Hz)
      lbj.ResolutionADC = 1;                      % ADC resolution (AD bit depth)
      
      % configure LabJack for analog input streaming
      
      errorCode = streamConfigure(lbj);
      if errorCode > 0
        fprintf(1,'Metrics: Unable to configure LabJack. Error %d.\n', errorCode);
        return
      end
    end
        
    function taskController(app, obj, ~, ~)
      handles = obj.UserData;
      data = handles.data;
      visStim = handles.visStim;
      saccades = handles.saccades;
      ampDur = handles.ampDur;
      %     rtDists = handles.rtDists;
      switch data.taskState
        case MetricsTaskState.taskStarttrial
          positionWindow(visStim);                                  % make sure stim window is positioned correctly
          if sum(app.offsetsDone) >= app.numOffsets               % finished another block
            app.offsetsDone = zeros(1, app.numOffsets);           % clear counters
            app.blocksDone = app.blocksDone + 1;                  % increment block counter
          end
          data.offsetIndex = ceil(rand() * app.numOffsets);        % randomly selected saccade step
          startIndex = data.offsetIndex;                            % mark the index where search starts
          app.centeringTrial = false;
          while true                                                % find an unused, doable step
            if app.offsetsDone(data.offsetIndex) == 0 && ...       % unused and doable
                          ~stepOutOfRange(visStim, data.offsetsDeg(data.offsetIndex))
              break;
            end
            data.offsetIndex = mod(data.offsetIndex, app.numOffsets) + 1; % try the next one
            if data.offsetIndex == startIndex                       % no steps doable from current position
              app.centeringTrial = true;                           % center the stimulus and try again
%               fprintf('centering the stimulus: at %.0f step asked %.0f max %.0f\n',...
%                       currentOffsetDeg(visStim), data.offsetsDeg(data.offsetIndex), maxDeg(visStim));
              break;
            end
          end
          %             fprintf('Currently at %.0f; step asked %.0f; max doable %.0f\n',...
          %                         currentOffsetDeg(visStim), data.offsetsDeg(data.offsetIndex), maxDeg(visStim));
          app.absStepIndex = mod(data.offsetIndex - 1, app.numOffsets / 2) + 1;
          data.stepSign = sign(data.offsetsDeg(data.offsetIndex));
          if app.testMode                                         	% debugging- connect DACO0 to AIN0
            data.voltage = min(5.0, visStim.currentOffsetDeg / 1000.0);
            analogOut(app.lbj, 0, 2.5 + data.voltage);
          end
          data.trialStartTimeS = clock;
          data.taskState = MetricsTaskState.taskSettle;             % go to settle state
        case MetricsTaskState.taskSettle
          if etime(clock, data.trialStartTimeS) > 0.015             % data settled for one taskTimer cycle
            data.trialStartTimeS = clock;                         	% reset the trial clock
            data.stimTimeS = data.prestimDurS + rand() * 0.125;   	% jitter the stimon time a bit
            data.samplesRead = 0;
            app.lbj.verbose = 0;
            startStream(app.lbj);
            app.lbj.verbose = 1;
            app.dataState = MetricsDataState.dataCollect;
            data.taskState = MetricsTaskState.taskPrestim;          % go to prestim state
          end
        case MetricsTaskState.taskPrestim
          if etime(clock, data.trialStartTimeS) > data.stimTimeS
            if app.centeringTrial
              centerStimulus(visStim);
            else
              stepStimulus(visStim, data.offsetsDeg(data.offsetIndex));
            end
            data.taskState = MetricsTaskState.taskPoststim;
          end
        case MetricsTaskState.taskPoststim
        case MetricsTaskState.taskEndtrial
          if ~app.centeringTrial
            [startIndex, endIndex] = processSignals(saccades, app, data);
            plotPosVel(handles.posVelPlots, handles, app, startIndex, endIndex, false);
            set(handles.calibrationText, 'string', sprintf('Calibration %.1f deg/V', saccades.degPerV));
            addAmpDur(ampDur, data.offsetIndex, startIndex, endIndex);
            plotAmpDur(ampDur, app);
          end
          data.trialStartTimeS = 0;
          data.taskState = MetricsTaskState.taskStarttrial;
      end
    end
    
    function taskError(~, ~, ~, ~)
      fprintf('timer error\n')
    end
    
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function openMetrics(app, varargin)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
      
      % This function has no output args, see OutputFcn.
      % hObject    handle to figure
      % eventdata  reserved - to be defined in a future version of MATLAB
      % handles    structure with handles and user data (see GUIDATA)
      % varargin   command line arguments to Metrics (see VARARGIN)
      
      % test mode requires connecting DAC0 to AIN0 and DAC1 to AIN1 on the LabJack
      if ~isempty(varargin)
        app.testMode = strcmp(varargin{1}, 'debug') || strcmp(varargin{1}, 'test');
        app.doStimDisplay = true;
      else
        app.testMode = true;
        app.doStimDisplay = true;
      end
      if app.testMode || ~app.doStimDisplay
        set(handles.warnText, 'string', 'Test Mode');
      end
      handles.output = hObject;                                       % select default command line output
      handles.saccades = MetricsSaccades;
      app.lbj = setupLabJack(app);
      if isempty(app.lbj)
        close all;
        return;
      end
      nyquistHz = app.lbj.SampleRateHz / 2.0;
      app.filter60Hz = design(fdesign.bandstop('Fp1,Fst1,Fst2,Fp2,Ap1,Ast,Ap2', ...
                55 / nyquistHz, 59 / nyquistHz, 61 / nyquistHz, 65 / nyquistHz, 1, 60, 1), 'butter');
      app.filter60Hz.persistentmemory = false;    % no piecemeal filtering of trace
      app.filter60Hz.states = 1;                      % uses scalar expansion.
      app.filterLP = design(fdesign.lowpass('Fp,Fst,Ap,Ast', 30 / nyquistHz, 120 / nyquistHz, 0.1, 40), 'butter');
      app.filterLP.persistentmemory = false;      % no piecemeal filtering of trace
      app.filterLP.states = 1;                      % uses scalar expansion.

      app.numChannels = app.lbj.numChannels;
      app.numOffsets = length(app.offsetsDeg);
      app.saccadeSamples = floor(app.saccadeTraceS * app.lbj.SampleRateHz);
      app.trialDurS = max(1.0, 2 * app.saccadeTraceS);
      app.trialSamples = floor(app.trialDurS * app.lbj.SampleRateHz);
      handles.posVelPlots = MetricsPosVelPlots(app);
      
      handles.data = MetricsTaskData();
      clearBuffers(app);
%       stepSizeDeg = 5.0;
%       handles.data.stepSizeDeg = stepSizeDeg;
      handles.visStim = MetricsStimulus(app);
      if (app.testMode)
        analogOut(app.lbj, 0, 2.5);                               % For debugging (AOuts to AIns)
      end
%       handles.ampDur = MetricsAmpDur(handles.ampDurAxes, handles.data.offsetsDeg, app.lbj.SampleRateHz);
      handles.ampDur = MetricsAmpDur(app);
      set(hObject, 'CloseRequestFcn', {@app.closeMetrics, handles});  % close function will close LabJack
      movegui(hObject, 'northeast');
      guidata(hObject, handles);                                      % save the selection
    end

    % Button pushed function: clearButton
    function clearButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      selection = uiconfirm(app.figure1, 'Really clear all data? (This cannot be undone).', 'Clear Data');
      switch selection
        case 'OK'
          clearAll(handles.saccades);
          clearAll(handles.ampDur, app);
          clearBuffers(app);
          plotPosVel(handles.posVelPlots, handles, app, 0, 0, true);
          set(handles.calibrationText, 'string', '');
          guidata(hObject, handles);
      end
    end

    % Button pushed function: loadDataButton
    function loadDataButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);
      
      MetricsControlState(handles, 'off', {})
      [fileName, filePath] = uigetfile('*.mat', 'Load Matlab Data Workspace', '~/Desktop');
      if fileName ~= 0
        oldTestMode = app.testMode;                               % keep testMode across data loads
        load([filePath fileName], 'd', 'a', 's');
        handles.data = d;
        app.testMode = oldTestMode;                      % keep testMode across data loads
        handles.ampDur = a;
        handles.saccades = s;
        set(handles.calibrationText, 'string', sprintf('Calibration %.1f deg/V', handles.saccades.degPerV));
        
        % saved axes handles generally aren't valid if we are in a new run.
        % Load the handles with the current axes handles.
        
        handles.ampDur.fHandle = app.ampDurAxes;                         % loaded axes handle might be invalid
        guidata(hObject, handles);                                      % save the selections
        
        [startIndex, endIndex] = processSignals(handles.saccades, app, d);
        plotPosVel(handles.posVelPlots, handles, app, startIndex, endIndex, true);
        handles.ampDur.lastN = 0;                                       % force ampDur to plot
        plotAmpDur(handles.ampDur, app);
      end
      MetricsControlState(handles, 'on', {})
    end

    % Button pushed function: saveDataButton
    function saveDataButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      
      % Saving the workspace for a GUI isn't simple.  What we have accessible in this
      % environment is mostly the handles.  If we save, it's an attempt to save
      % handles, which doesn't work.  Instead, we get a list of all the properties
      % from the MetricsTaskData class, and then use eval statement to assign those to
      % local variable and save them (one by one).
      MetricsControlState(handles, 'off', {})
      [fileName, filePath] = uiputfile('*.mat', 'Save Matlab Data Workspace', '~/Desktop/MetricsData.mat');
      if fileName ~= 0
        d = handles.data;
        a = handles.ampDur;
        s = handles.saccades;
        save([filePath fileName], 'd', 'a', 's');
      end
      MetricsControlState(handles, 'on', {})
    end

    % Button pushed function: savePlotsButton
    function savePlotsButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      
      MetricsControlState(handles, 'off', {})
      progress = uiprogressdlg(app.figure1, 'title', 'Save Plots', 'message', 'Saving to ~/Desktop/MetricsData', ...
        'indeterminate','on');
      timeString = savePlot(app.posAxes, '~/Desktop/MetricsData/Pos/', 'MT', 'png');
      savePlot(app.velAxes, '~/Desktop/MetricsData/Vel/', 'MT', 'png', timeString);
      savePlot(app.avgPosAxes, '~/Desktop/MetricsData/AvgPos/', 'MT', 'png', timeString);       
      savePlot(app.avgVelAxes, '~/Desktop/MetricsData/AvgVel/', 'MT', 'png', timeString);       
      savePlot(app.ampDurAxes, '~/Desktop/MetricsData/AmpDur/', 'MT', 'png', timeString);       
      close(progress);
      MetricsControlState(handles, 'on', {})
    end

    % Button pushed function: startButton
    function startButton_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      
      % hObject    handle to startButton (see GCBO)
      % eventdata  reserved - to be defined in a future version of MATLAB
      % handles    structure with handles and user data (see GUIDATA)
      
      if strcmp(get(handles.startButton, 'String'), 'Start') % if start button, do the following
        fprintf(1,'Saccade Metrics v2.0\n %s\n', datestr(clock));
        viewDistanceCM = str2double(get(handles.viewDistanceText, 'string'));
        limitDistanceCM = maxViewDistanceCM(handles.visStim, max(handles.data.offsetsDeg));
        if viewDistanceCM > limitDistanceCM
          errordlg(sprintf('Viewing distance must be <= %d cm', floor(limitDistanceCM)));
          return;
        end
        setViewDistanceCM(handles.visStim, app);
        handles.saccades.thresholdDeg = str2double(get(handles.thresholdDegText, 'string'));
        % create timer to control the task
        taskRateHz = 25;
        handles.taskTimer = timer('Name', 'TaskTimer', 'ExecutionMode', 'fixedRate',...
          'Period', 1.0/taskRateHz, 'UserData', handles, 'ErrorFcn', {@app.taskError, handles}, 'TimerFcn',...
          @app.taskController);
%           {@app.taskController, [handles.posAxes handles.avgPosAxes handles.velAxes handles.avgVelAxes]});
        
        % create timer to get data from LabJack
        handles.data.taskState = MetricsTaskState.taskStarttrial;
        handles.data.trialStartTimeS = 0;
        app.dataState = MetricsDataState.dataIdle;
        dataCollectRateHz = 25;                       % Fast enough to prevent overflow w/o blocking other activity
        handles.dataTimer = timer('Name', 'LabJackData', 'ExecutionMode', 'fixedRate',...
          'Period', 1.0 / dataCollectRateHz, 'UserData', handles, 'ErrorFcn', {@app.taskError, handles},...
          'TimerFcn', {@app.collectData}, 'StartDelay', 0.050); % StartDelay allows other parts of the gui to execute
        
        % set the gui button to "running" state
        set(handles.startButton, 'String', 'Stop', 'BackgroundColor', 'red');
        MetricsControlState(handles, 'off', {handles.startButton})
        % save timers to handles and update the GUI display
        
        %% Start plots, data pickup, and data acquisition
        start(handles.dataTimer);
        start(handles.taskTimer);
        
        %% Stop -- we're already running, so it's a the stop button
      else % stop
        stop(timerfind);                                                        % stop/delete timers; pause data stream
        delete(timerfind);
        handles.dataTimer = 0;
        handles.taskTimer = 0;
        stopStream(app.lbj);
        set(handles.startButton, 'string', 'Start','backgroundColor', 'green');
        MetricsControlState(handles, 'on', {handles.startButton})
        drawnow;
        centerStimulus(handles.visStim);                                        % recenter fixspot
      end
      guidata(hObject, handles);
    end

    % Value changed function: viewDistanceText
    function viewDistanceTextValueChanged(app, event)
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
      setViewDistanceCM(handles.visStim, app);
      guidata(hObject, handles);
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.figure1 = uifigure('Visible', 'off');
      app.figure1.Name = 'NSCI 20100';
      app.figure1.Tag = 'figure1';
      app.posAxes = uiaxes(app.figure1);
      app.posAxes.Tag = 'axes1';
      app.avgPosAxes = uiaxes(app.figure1);
      app.avgPosAxes.Tag = 'axes2';
      app.velAxes = uiaxes(app.figure1);
      app.velAxes.Tag = 'velAxes';
      app.avgVelAxes = uiaxes(app.figure1);
      app.avgVelAxes.Tag = 'axes4';
      app.ampDurAxes = uiaxes(app.figure1);
      app.ampDurAxes.Tag = 'axes5';
      app.startButton = uibutton(app.figure1, 'push');
      app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButton_Callback, true);
      app.startButton.Tag = 'startButton';
      app.startButton.Text = 'Start';
      app.clearButton = uibutton(app.figure1, 'push');
      app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButton_Callback, true);
      app.clearButton.Tag = 'clearButton';
      app.clearButton.Text = 'Clear Data';
      app.viewDistanceText = uieditfield(app.figure1, 'text');
      app.viewDistanceText.ValueChangedFcn = createCallbackFcn(app, @viewDistanceTextValueChanged, true);
      app.viewDistanceText.Tag = 'viewDistanceText';
      app.viewDistanceText.Value = '50';
      app.text2 = uilabel(app.figure1);
      app.text2.Tag = 'text2';
      app.text2.Text = 'Viewing Distance (cm):';
      app.thresholdDegText = uieditfield(app.figure1, 'text');
      app.thresholdDegText.Tag = 'thresholdDegText';
      app.thresholdDegText.Value = '3';
      app.text3 = uilabel(app.figure1);
      app.text3.Tag = 'text3';
      app.text3.Text = 'Saccade Threshold (deg):';
      app.savePlotsButton = uibutton(app.figure1, 'push');
      app.savePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @savePlotsButton_Callback, true);
      app.savePlotsButton.Tag = 'savePlotsButton';
      app.savePlotsButton.Text = 'Save Plots';
      app.titleText = uilabel(app.figure1);
      app.titleText.Tag = 'titleText';
      app.titleText.Text = 'NSCI 20100 Saccade Metrics Laboratory';
      app.saveDataButton = uibutton(app.figure1, 'push');
      app.saveDataButton.ButtonPushedFcn = createCallbackFcn(app, @saveDataButton_Callback, true);
      app.saveDataButton.Tag = 'saveDataButton';
      app.saveDataButton.Text = 'Save Data';
      app.loadDataButton = uibutton(app.figure1, 'push');
      app.loadDataButton.ButtonPushedFcn = createCallbackFcn(app, @loadDataButton_Callback, true);
      app.loadDataButton.Tag = 'loadDataButton';
      app.loadDataButton.Text = 'Load Data';
      app.warnText = uilabel(app.figure1);
      app.warnText.Tag = 'warnText';
      app.warnText.Text = '';
      app.calibrationText = uilabel(app.figure1);
      app.calibrationText.Tag = 'calibrationText';
      app.calibrationText.Text = '';
      app.Filter60Hz = uicheckbox(app.figure1);
      app.Filter60Hz.Tag = 'filter60Hz';
      app.Filter60Hz.Text = 'Filter 60 Hz';
      % Show the figure after all components are created
      app.figure1.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = Metrics(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.figure1)

      % Execute the startup function
      runStartupFcn(app, @(app)openMetrics(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figure1)
    end
  end
end