classdef StretchReceptor < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figure1                   matlab.ui.Figure
    countLimitText            matlab.ui.control.EditField
    longCountStopLabelText_2  matlab.ui.control.Label
    thresholdSlider           matlab.ui.control.Slider
    Label                     matlab.ui.control.Label
    longCountStopLabelText    matlab.ui.control.Label
    isiLimitText              matlab.ui.control.EditField
    longCountWindowLabelText  matlab.ui.control.Label
    longWindowMSText          matlab.ui.control.EditField
    limitText                 matlab.ui.control.Label
    text13                    matlab.ui.control.Label
    volumeSlider              matlab.ui.control.Slider
    text12                    matlab.ui.control.Label
    filterMenu                matlab.ui.control.DropDown
    retriggerButton           matlab.ui.control.Button
    singleSpikeCheckbox       matlab.ui.control.CheckBox
    text10                    matlab.ui.control.Label
    vPerDivButton             matlab.ui.control.DropDown
    text9                     matlab.ui.control.Label
    clearButton               matlab.ui.control.Button
    contMSPerDivButton        matlab.ui.control.DropDown
    warnText                  matlab.ui.control.Label
    titleText                 matlab.ui.control.Label
    savePlotsButton           matlab.ui.control.Button
    startButton               matlab.ui.control.Button
    countAxes                 matlab.ui.control.UIAxes
    isiAxes                   matlab.ui.control.UIAxes
    vTrigAxes                 matlab.ui.control.UIAxes
    vContAxes                 matlab.ui.control.UIAxes
  end


  properties (Access = public)
    contMSPerDiv;             % ms per division for the continuous plot
    contSamples;              % number of samples in the continuous plot
    contTimeDivs = 20;        % number of time divisions in the continuous plot
    countPlot;                % handle to SRCountPlot
    dataTimer;                % timer for collecting data
    doClearTrigger;           % clear trigger plot on next update
    fakeSpikeRateHz = 12.5;   % rate for fake spikes
    filter;                   % low pass filter that is currently in use
    filters;                  % the set of possible low pass filters
    filteredTrace;            % filtered version of samples
    inSpike = false;          % flag showing that spike finding routine is in middle of a spike
    isiPlot;                  % handle to SRISIPlot
    lbj;                      % handle for LabJack object
    lbjExists;                % flag whether LabJack hardware is present
    longWindowMS              % long spike count window
    maxContSamples;           % the maximum possible number of samples for continue trace
    rawData;                  % most recent raw samples of data
    resultsRow table          % 1-row table holding the latest stats to be saved
    samplesRead = 0;          % number of samples that have been read
    signals;                  % handle to SRSignals object
    spikeIndices;             % indices for unplotted spikes in continuous trace
    spikePlots;               % handle to SRPlots object
    stopAtTraceEnd = false;   % flag for displaying a single continous trace
    thresholdV = 1.0;         % used by signals and plots
    testMode = false;         % flag for testing mode
    updateCount = 0;          % count of display updates
    vDivs = 6;                % number of voltage (y) divisions
    vPerDiv = 1.0;            % volts per division
  end

  properties (Access = private)
  end

  methods (Access = public)

    function atLimit = atLimit(app)
      atLimit = (app.countPlot.numLongCounts >= str2double(app.countLimitText.Value)) || ...
                (app.isiPlot.numISIs >= str2double(app.isiLimitText.Value));
      if atLimit
        app.startButton.Enable = false;
        app.limitText.Text = 'At requested number of ISIs or counts (increase limit or clear data)';
      else
        app.startButton.Enable = true;
        app.limitText.Text = '';
      end
    end

    % doStartButton -- do the functions associated with a press of the start button
    function doStartButton(app)
      % Start request
      % T = datetime('now');
      % T.Format = 'HH:mm:ss.SSS';
      % fprintf('%s doStartButton: Received click\n', T);
      if strcmp(app.startButton.Text, 'Start')              % if start button, do the following
        if atLimit(app)
          return;
        end
        app.stopAtTraceEnd = false;                         % clear any requests to stop at end of next trace
        app.startButton.BackgroundColor = 'red';
        app.startButton.Text = 'Stop';
        SRControlState(app, 'off', {app.startButton, app.clearButton})
        % T = datetime('now');
        % T.Format = 'uuuu/MM/dd HH:mm:ss.SSS';
        % fprintf('%s doStartButton: calling doStart\n', T);
        doStart(app);
        % Stop request -- we're already running, so it's a the stop button
      elseif strcmp(app.startButton.Text, 'Stop')           % if start button, do the following
        % T = datetime('now');
        % T.Format = 'HH:mm:ss.SSS';
        % fprintf('%s doStartButton: requesting stop\n', T);
        app.stopAtTraceEnd = true;                          % flag a stop at the end of this trace
        app.startButton.BackgroundColor = 'yellow';
        app.startButton.Text = 'Stopping';

        plotISI(app.isiPlot, app);

        drawnow;
        % Stop immediately -- already stopping, so we've been asked to stop immediately
      else
        % T = datetime('now');
        % T.Format = 'uuuu/MM/dd HH:mm:ss.SSS';
        % fprintf('%s doStartButton: calling doStop immediately\n', T);
        doStop(app);
      end
    end

  end

  methods (Access = private)

    function closeSR(app, ~, ~)
      % this function is called  when the user closes the main window
      % close the timer and clear the LabJack handle

      % fist check whether the task is running
      if ~strcmp(app.startButton.Text, 'Start')
        return;
      end
      selection = uiconfirm(app.figure1, 'Really exit? Unsaved data will be lost.', 'Exit Request');
      switch selection
        case 'OK'
          try stop(timerfind); catch, end
          try delete(timerfind); catch, end
          try clear('app.lbj'); catch, end
          delete(app.figure1);                                              % close the program window
        case 'Cancel'
          return
      end
    end

    function doClear(app)
      app.spikeIndices = [];
      app.inSpike = false;
      app.samplesRead = 0;
      app.doClearTrigger = false;
      app.updateCount = 0;
      clearAll(app.countPlot, app)
      clearAll(app.isiPlot, app)
      clearAll(app.spikePlots, app)
      clearAll(app.signals, app)
      drawnow;
    end

    % doStart -- start data collection
    function doStart(app)
      updateRateHz = 10;                                          % prevent overflow w/o blocking other activity
      if ~isempty(app.dataTimer)
        fprintf('doStart: ERROR: found existing dataTimer\n');
        try stop(timerfind); catch, end
        try delete(timerfind); catch, end
        app.dataTimer = [];
        return;
      end
      % set the gui button to "running" state
      % clear the plots
      if app.singleSpikeCheckbox.Value
        clearContPlot(app.spikePlots, app);
      else
        clearAll(app.spikePlots, app);
        clearAll(app.signals, app);
      end

      % Start plots, data pickup, and data acquisition
      app.dataTimer = timer('name', 'Update', 'ExecutionMode', 'fixedRate', 'busyMode', 'drop',...
        'Period', 1.0 / updateRateHz, 'TimerFcn', {@app.update}, 'ErrorFcn', {@app.updateError},...
        'StartDelay', 0.100);                                     % startDelay allows rest of the gui to execute
      startStream(app.lbj);
      start(app.dataTimer);
    end

    % doStop -- stop data collection
    function doStop(app, onTheFly)
      try stop(timerfind); catch, end
      try delete(timerfind); catch, end
      app.dataTimer = [];
      stopStream(app.lbj);
      clearAll(app.signals, app);                              % stop audio processing
      % On the fly stops involve clearing while running.  For those, we
      % don't restore the displays
      if nargin < 2 || ~onTheFly
        app.startButton.BackgroundColor = 'green';
        app.startButton.Text = 'Start';
        SRControlState(app, 'on', {app.startButton, app.clearButton})
      end
    end

    function readData(app, ~, ~)
      % read and process any new data
      % the LabJack marks lost data as -9999.  We clip it out and don't do anything more. Typically lost data appear
      % at the end of the trace (after the buffer has filled). Oddly, there is generally one spurious large value
      % (+10.115) that appears exactly 8 samples before the first error values.  We clip that out as well.
      % When the LabJack is open circuit, all values are +10.115 V. If no values are passed through, the display
      % shows nothing and doesn't stop properly. In that case, we display all zeros instead.

      % if ~app.lbjExists
      %   numNewSamples = 50;
      %   app.rawData(app.samplesRead + 1:app.samplesRead + numNewSamples) = 0;
      %   app.samplesRead = app.samplesRead + numNewSamples;          % total number of samples in continuous trace
      %   return
      % end
      totalSamples = 0;                                             % keep sampling until at least 50 samples
      for rep = 1:3
        [samplesIn, ~] = getStreamData(app.lbj);                    % get stream data
        if isempty(samplesIn)                                       % nothing in buffer, all done
          break;
        end
        numSamples = length(samplesIn);                             % save the number read
        samplesIn(samplesIn > 10.0 | samplesIn == -9999) = [];      % clip out all error values
        if isempty(samplesIn)                                       % if nothing is left,
          samplesIn = zeros(1, numSamples);                         % make a buffer of zeros the correct length
        end
        numNewSamples = length(samplesIn);                          % number of samples newly read
        % append the data without regard for whether we go past the display
        % limit. That will be addressed later in update();
        app.rawData(app.samplesRead + 1:app.samplesRead + numNewSamples) = samplesIn;
        app.samplesRead = app.samplesRead + numNewSamples;          % total number of samples in continuous trace
        totalSamples = totalSamples + numNewSamples;                % total new samples this call of readData
        if totalSamples > 50                                        % if we've got 50, we're done
          break;
        end
      end
    end

    % setLimits -- used when plot scaling changes
    function setLimits(app)
      app.samplesRead = 0;
      app.spikeIndices = [];
      app.contSamples = app.contMSPerDiv / 1000.0 * app.lbj.SampleRateHz * app.contTimeDivs;
      vLimit = app.vPerDiv * app.vDivs / 2.0;
      app.thresholdV = max(-vLimit * 0.9, min(vLimit * 0.9, app.thresholdSlider.Value));
      app.thresholdSlider.Value = app.thresholdV;
      app.thresholdSlider.Limits = [-vLimit, vLimit];
      app.thresholdSlider.MinorTicks = linspace(-vLimit, vLimit, 2.0 * app.vDivs + 1);
    end

    % timer to update data and display
    function update(app, ~, ~)
      try
        app.updateCount = app.updateCount + 1;
        readData(app);
        overRun = processSignals(app.signals, app);
        doContPlot(app.spikePlots, app, overRun);
        doTriggerPlot(app.spikePlots, app);
        % if we have reached beyond the end of the displayable part of the buffer, make adjustments.
        if overRun > 0
          if app.stopAtTraceEnd               % requested to stop at the end of the previous trace
            doStop(app);
          end
        end
        % if we've reach the limit for window counts, stop at the end of this display trace
        if atLimit(app) && strcmp(app.startButton.Text, 'Stop')
          doStartButton(app);
        end
      catch e
        fprintf(1,'\nError message: %s\n', e.message);
        fprintf(1,'Error identifier: %s',e.identifier);
        for s = 1:length(e.stack)
          fprintf('Stack %d: file %s name %s line %d\n', s, e.stack(s).file, e.stack(s).name, e.stack(s).line);
        end
        return;
      end
    end

    % update timer error
    function updateError(~, ~, ~)
      fprintf('update timer error');
      dbstack
    end

  end


  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function openSR(app, varargin)
      set(app.figure1, 'visible', 'off');
      % Check for existing instance using tag
      if ~isFirstAppInstance(app.figure1)
        delete(app);
        return;
      end      
      fprintf(1,'\nStretchReceptor v2.7\n %s\n', string(datetime));
      cleanOldFiles('SRData');
      deleteOtherTaskFiles('SRData')
      % test mode requires connecting DAC0 to AIN0 and DAC1 to AIN1 on the LabJack
      app.testMode = false;
      if ~isempty(varargin) && (strcmp(varargin{1}, 'debug') || strcmp(varargin{1}, 'test'))
        if confirmTestMode()
          app.testMode = true;
          app.warnText.Text = 'Test Mode';
        end
      end
      app.contMSPerDiv = str2double(app.contMSPerDivButton.Items{endsWith(app.contMSPerDivButton.Items, ...
        app.contMSPerDivButton.Value)});
      set(app.figure1, 'CloseRequestFcn', {@app.closeSR});            % close function will close LabJack
      [app.lbj, app.lbjExists] = setupLabJack(app.testMode, 5000);
      if isempty(app.lbj)
        return;
      end
      maxMSPerDiv = str2double(app.contMSPerDivButton.Items{end});
      validateWindows_Callback(app);
      app.longWindowMS = str2double(app.longWindowMSText.Value);
      app.maxContSamples = app.contTimeDivs * maxMSPerDiv / 1000.0 * app.lbj.SampleRateHz * 2;
      app.filteredTrace = zeros(app.maxContSamples, 1);           % filtered voltage trace
      app.rawData = zeros(app.maxContSamples, 1);                 % raw data, extra for overflow
      setLimits(app);
      filterStrings = app.filterMenu.Items;
      app.filters = cell(length(filterStrings), 1);
      for f = 1:length(filterStrings)
        cutOffHz = str2double(filterStrings{f});
        theFilter = design(fdesign.highpass('Fst,Fp,Ast,Ap', 0.5 * cutOffHz / app.lbj.SampleRateHz, ...
          2 * cutOffHz / app.lbj.SampleRateHz, 60, 1), 'butter');
        theFilter.persistentmemory = true;                            % piecemeal filtering of trace
        theFilter.states = 1;                                         % uses scalar expansion.
        app.filters{f} = theFilter;
        if strcmp(filterStrings{f}, app.filterMenu.Value)
          app.filter = theFilter;                                     % set the active filter
        end
      end
      app.countPlot = SRCountPlot(app);                               % create isi plot before signal processing
      app.isiPlot = SRISIPlot(app);                                   % create isi plot before signal processing
      app.signals = SRSignalProcess(app);                             % create signal processing after data
      app.spikePlots = SRSpikePlots(app);
      app.resultsRow = table(strings(1,1), NaN(1,1), NaN(1,1), NaN(1,1), NaN(1,1), NaN(1,1), NaN(1,1), NaN(1,1), ...
            'VariableNames', {'Time', 'Window (ms)', 'Num ISIs','Drift (%)', 'Num Counts',...
            'Mean (spk/s)','SD (spk/s)','JND (spk/s)'});
      if app.testMode && app.lbjExists
        analogOut(app.lbj, 0, 2.5);                                   % For debugging (AOuts to AIns)
      end
      movegui(app.figure1, 'northeast');
      set(app.figure1, 'visible', 'on');
    end

    % Button pushed function: clearButton
    function clearButton_Callback(app, event)
      if strcmp(app.startButton.Text, 'Start')                        % currently paused
        doClear(app);                                                 % clear immediately
        atLimit(app);                                            % update the warning text
      else
        doStop(app, true);                                            % stop data collection
        doClear(app);                                                 % clear the plots and data
        doStart(app);                                                 % re-start data collection
      end
    end

    % Value changed function: contMSPerDivButton
    function contMSPerDivButton_Callback(app, event)
      newValue = str2double(app.contMSPerDivButton.Value);
      if newValue ~= app.contMSPerDiv
        app.contMSPerDiv = newValue;
        setLimits(app);
        clearContPlot(app.spikePlots, app)
      end
    end

    % Value changed function: filterMenu
    function filterMenu_Callback(app, event)
      app.filter = app.filters{strcmp(app.filterMenu.Items, app.filterMenu.Value)};
    end

    % Button pushed function: retriggerButton
    function retriggerButton_Callback(app, event)
      clearTriggerPlot(app.spikePlots, app);
    end

    % Button pushed function: savePlotsButton
    function savePlotsButton_Callback(app, event)
      SRControlState(app, 'off', {})
      progress = uiprogressdlg(app.figure1, 'title', 'Save Data', 'message', ['Saving to ', dataRoot(), '/SRData'], ...
        'indeterminate','on');
      timeString = savePlot(app.vContAxes, 'SRData/FullTrace/', 'SR', 'pdf');
      savePlot(app.vTrigAxes, 'SRData/SpikeTrace/', 'SR', 'pdf', timeString);
      savePlot(app.isiAxes, 'SRData/ISI/', 'SR', 'pdf', timeString);
      savePlot(app.countAxes, 'SRData/SpikeCounts/', 'SR', 'pdf', timeString);

      folderPath = [dataRoot(), '/SRData/Data/'];
      if ~isfolder(folderPath)
        mkdir(folderPath);
      end
      dateString = string(datetime('now', 'format', 'MMM-dd'));
      fileName = sprintf('SR-%s.xlsx', dateString);
      if ~isnan(app.resultsRow.("Num Counts")) && app.resultsRow.("Num Counts") > 0
        app.resultsRow.Time = string(datetime('now','Format','HH:mm:ss'));
        t = table();
        fullPath = fullfile(folderPath, fileName);
        if isfile(fullPath)
          system(sprintf('cp %s%s %stemp.xlsx', folderPath, fileName, folderPath));
          t = readtable(fullPath, 'VariableNamingRule', 'preserve');
          if ~isempty(t)
            expected = app.resultsRow.Properties.VariableNames;
            % If the file has different columns, try to align; otherwise error
            if ~isequal(t.Properties.VariableNames, expected)     % Attempt to keep only expected columns if they exist
              if all(ismember(expected, t.Properties.VariableNames))
                t = t(:, expected);
              else
                error('Saved file has unexpected columns.');
              end
            end
          end
        end
        try
          t = [t; app.resultsRow];
          writetable(t, fullPath, 'WriteMode','overwrite');
          backupFile(fullPath);
        catch ME
            originalSize = get(0, 'DefaultUIControlFontSize');
            set(0, 'DefaultUIControlFontSize', 14);
            msg = sprintf(['Unable to write data file.\n\n' 'Common causes:\n' '- File is open in Excel\n' ...
                '- File permissions are restricted\n' '- Excel file format was altered\n\n' ...
                'Error message:\n%s'], ME.message);
            questdlg(msg, 'Error Writing Data File', 'OK', 'OK');
            set(0, 'DefaultUIControlFontSize', originalSize);
        end
        system(sprintf('rm -f %stemp.xlsx', folderPath));
      end
      close(progress);
      figure(app.figure1);
      SRControlState(app, 'on', {})
    end

    % Value changed function: singleSpikeCheckbox
    function singleSpikeCheckbox_Callback(app, event)
      if app.singleSpikeCheckbox.Value
        app.retriggerButton.Enable = true;
        clearTriggerPlot(app.spikePlots, app);
      else
        app.retriggerButton.Enable = false;
      end
    end

    % Button pushed function: startButton
    function startButton_Callback(app, event)
      doStartButton(app)
    end

    % Value changed function: thresholdSlider
    function thresholdSlider_Callback(app, event)
      app.thresholdV = app.thresholdSlider.Value;
      updateThresholdIfNeeded(app.spikePlots, app);
    end

    % Value changed function: vPerDivButton
    function vPerDivButton_Callback(app, event)
      newValue = str2double(app.vPerDivButton.Value);
      if newValue ~= app.vPerDiv
        app.vPerDiv = newValue;
        setLimits(app);
        clearAll(app.spikePlots, app);
      end
    end

    % Value changed function: volumeSlider
    function volumeSlider_Callback(app, event)
      setVolume(app.signals, app);
    end

    % Value changed function: countLimitText, isiLimitText
    function stopAfterTextChanged(app, event)
      atLimit(app);
    end

    % Value changed function: longWindowMSText
    function validateWindows_Callback(app, event)
      app.longWindowMS = str2double(app.longWindowMSText.Value);
      invalid = false;
      if app.longWindowMS < 100
        uiconfirm(app.figure1, 'Counting windows must be >= 100 ms.', 'Invalid Counting Window', 'icon', 'warning', ...
          'options', {'OK'});
        app.longWindowMS = max(app.longWindowMS, 100);
        invalid = true;
      end
      if invalid
        app.longWindowMSText.Value = sprintf('%d', app.longWindowMS);
      end
    end

    % Value changing function: thresholdSlider
    function thresholdSlider_ValueChanging(app, event)
    app.thresholdV = event.Value;              % <-- continuous value while dragging
    updateThresholdIfNeeded(app.spikePlots, app);
    % Optional: keep UI responsive if you notice lag
    % drawnow limitrate nocallbacks      
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.figure1 = uifigure('Visible', 'off');
      colormap(app.figure1, 'parula');
      app.figure1.Tag = 'StretchReceptor';
      app.vContAxes = uiaxes(app.figure1);
      app.vContAxes.Tag = 'axes1';
      app.vTrigAxes = uiaxes(app.figure1);
      app.vTrigAxes.Tag = 'axes2';
      app.isiAxes = uiaxes(app.figure1);
      xlabel(app.isiAxes, 'Inter-spike Interval (ms)')
      app.isiAxes.Tag = 'axes3';
      app.countAxes = uiaxes(app.figure1);
      xlabel(app.countAxes, 'Spike Counts')
      app.countAxes.Tag = 'axes3';
      app.startButton = uibutton(app.figure1, 'push');
      app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButton_Callback, true);
      app.startButton.Tag = 'startButton';
      app.startButton.Text = 'Start';
      app.savePlotsButton = uibutton(app.figure1, 'push');
      app.savePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @savePlotsButton_Callback, true);
      app.savePlotsButton.Tag = 'savePlotsButton';
      app.savePlotsButton.Text = 'Save Data';
      app.titleText = uilabel(app.figure1);
      app.titleText.Tag = 'titleText';
      app.titleText.Text = 'NSCI 20100 Stretch Receptor Laboratory';
      app.warnText = uilabel(app.figure1);
      app.warnText.Tag = 'warnText';
      app.warnText.Text = '';
      app.contMSPerDivButton = uidropdown(app.figure1);
      app.contMSPerDivButton.Items = {'10', '25', '50', '100', '250', '500', '1000'};
      app.contMSPerDivButton.ValueChangedFcn = createCallbackFcn(app, @contMSPerDivButton_Callback, true);
      app.contMSPerDivButton.Tag = 'contMSPerDivButton';
      app.contMSPerDivButton.Value = '100';
      app.clearButton = uibutton(app.figure1, 'push');
      app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButton_Callback, true);
      app.clearButton.Tag = 'clearButton';
      app.clearButton.Text = 'Clear All';
      app.text9 = uilabel(app.figure1);
      app.text9.Tag = 'text9';
      app.text9.Text = ' Time per Division (ms)';
      app.vPerDivButton = uidropdown(app.figure1);
      app.vPerDivButton.Items = {'0.1', '0.2', '0.5', '1.0', '2.0', '5.0'};
      app.vPerDivButton.ValueChangedFcn = createCallbackFcn(app, @vPerDivButton_Callback, true);
      app.vPerDivButton.Tag = 'vPerDivButton';
      app.vPerDivButton.Value = '1.0';
      app.text10 = uilabel(app.figure1);
      app.text10.Tag = 'text10';
      app.text10.Text = ' Volts per Division';
      app.singleSpikeCheckbox = uicheckbox(app.figure1);
      app.singleSpikeCheckbox.ValueChangedFcn = createCallbackFcn(app, @singleSpikeCheckbox_Callback, true);
      app.singleSpikeCheckbox.Tag = 'singleSpikeCheckbox';
      app.singleSpikeCheckbox.Text = 'Single Spike';
      app.retriggerButton = uibutton(app.figure1, 'push');
      app.retriggerButton.ButtonPushedFcn = createCallbackFcn(app, @retriggerButton_Callback, true);
      app.retriggerButton.Tag = 'retriggerButton';
      app.retriggerButton.Enable = 'off';
      app.retriggerButton.Text = 'Re-trigger';
      app.filterMenu = uidropdown(app.figure1);
      app.filterMenu.Items = {'1', '100', '250'};
      app.filterMenu.ValueChangedFcn = createCallbackFcn(app, @filterMenu_Callback, true);
      app.filterMenu.Tag = 'filterMenu';
      app.filterMenu.Value = '1';
      app.text12 = uilabel(app.figure1);
      app.text12.Tag = 'text12';
      app.text12.Text = 'High-pass Filter (Hz)';
      app.volumeSlider = uislider(app.figure1);
      app.volumeSlider.Limits = [2 6];
      app.volumeSlider.MajorTicks = [];
      app.volumeSlider.ValueChangedFcn = createCallbackFcn(app, @volumeSlider_Callback, true);
      app.volumeSlider.MinorTicks = [2 2.4 2.8 3.2 3.6 4 4.4 4.8 5.2 5.6 6];
      app.volumeSlider.Tag = 'volumeSlider';
      app.volumeSlider.Value = 4;
      app.text13 = uilabel(app.figure1);
      app.text13.Tag = 'text13';
      app.text13.Text = 'Volume';
      app.limitText = uilabel(app.figure1);
      app.limitText.Tag = 'limitText';
      app.limitText.Text = '';
      app.longWindowMSText = uieditfield(app.figure1, 'text');
      app.longWindowMSText.ValueChangedFcn = createCallbackFcn(app, @validateWindows_Callback, true);
      app.longWindowMSText.Tag = 'countDurMS';
      app.longWindowMSText.Value = '1000';
      app.longCountWindowLabelText = uilabel(app.figure1);
      app.longCountWindowLabelText.Tag = 'text2';
      app.longCountWindowLabelText.Text = 'Counting Window (ms):';
      app.isiLimitText = uieditfield(app.figure1, 'text');
      app.isiLimitText.ValueChangedFcn = createCallbackFcn(app, @stopAfterTextChanged, true);
      app.isiLimitText.Tag = 'stopAfterISI';
      app.isiLimitText.Value = '5000';
      app.longCountStopLabelText = uilabel(app.figure1);
      app.longCountStopLabelText.Tag = 'text2';
      app.longCountStopLabelText.Text = 'Stop When ISIs >=';
      app.Label = uilabel(app.figure1);
      app.Label.Text = '';
      app.thresholdSlider = uislider(app.figure1);
      app.thresholdSlider.Limits = [-1 1];
      app.thresholdSlider.MajorTicks = [];
      app.thresholdSlider.MajorTickLabels = {''};
      app.thresholdSlider.ValueChangedFcn = createCallbackFcn(app, @thresholdSlider_Callback, true);
      app.thresholdSlider.ValueChangingFcn = createCallbackFcn(app, @thresholdSlider_ValueChanging, true);
      app.thresholdSlider.MinorTicks = [-1 -0.9 -0.8 -0.7 -0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
      app.thresholdSlider.Tag = 'thresholdSlider';
      app.thresholdSlider.Value = 1;
      app.longCountStopLabelText_2 = uilabel(app.figure1);
      app.longCountStopLabelText_2.Tag = 'text2';
      app.longCountStopLabelText_2.Text = 'Stop When Counts >=';
      app.countLimitText = uieditfield(app.figure1, 'text');
      app.countLimitText.ValueChangedFcn = createCallbackFcn(app, @stopAfterTextChanged, true);
      app.countLimitText.Tag = 'stopAfterCount';
      app.countLimitText.Value = '5000';
      % Show the figure after all components are created
      app.figure1.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = StretchReceptor(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.figure1)

      % Execute the startup function
      runStartupFcn(app, @(app)openSR(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figure1)
    end
  end
end